classdef EEC201FinalProject < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                  matlab.ui.Figure
        MEditField                matlab.ui.control.NumericEditField
        MEditFieldLabel           matlab.ui.control.Label
        NEditField                matlab.ui.control.NumericEditField
        NEditFieldLabel           matlab.ui.control.Label
        ClearButton               matlab.ui.control.Button
        PlotButton                matlab.ui.control.Button
        PredictButton             matlab.ui.control.Button
        ProcessedButtonGroup      matlab.ui.container.ButtonGroup
        NoButton                  matlab.ui.control.RadioButton
        YesButton                 matlab.ui.control.RadioButton
        VisualizationButtonGroup  matlab.ui.container.ButtonGroup
        SpectrogramButton         matlab.ui.control.RadioButton
        TimeButton                matlab.ui.control.RadioButton
        TestDataListBox           matlab.ui.control.ListBox
        TestDataListBoxLabel      matlab.ui.control.Label
        TrainingDataListBox       matlab.ui.control.ListBox
        TrainingDataListBoxLabel  matlab.ui.control.Label
        Lamp2                     matlab.ui.control.Lamp
        Lamp                      matlab.ui.control.Lamp
        LoadTestDataButton        matlab.ui.control.Button
        LoadTrainingDataButton    matlab.ui.control.Button
        UITable                   matlab.ui.control.Table
        UIAxes                    matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        trainx_array
        trainx_preprocessed_array
        trainf_array
        traint_array
        trainmfcc_array
        train_fs
        trainFileNames

        testx_array
        testx_preprocessed_array
        testf_array
        testt_array
        testmfcc_array
        test_fs
        testFileNames

        N
        M
        CodebookSize

        codebook
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.N = 256;
            app.M = round(app.N/3);
            app.CodebookSize = 32;

            app.trainFileNames = [];
            app.testFileNames = [];
        end

        % Button pushed function: LoadTrainingDataButton
        function LoadTrainingDataButtonPushed(app, event)
            myTrainDir = uigetdir;

            if myTrainDir ~= 0
                myTrainFiles = dir(fullfile(myTrainDir,'*.wav'));
                if ~isempty(myTrainFiles)
                    app.trainFileNames = {myTrainFiles.name};
                    app.Lamp.Color = [0,1,0];
                    app.TrainingDataListBoxLabel.Enable = "on";
                    app.TrainingDataListBox.Enable = "on";
                    app.TrainingDataListBox.Items = app.trainFileNames;
                    app.VisualizationButtonGroup.Enable = "on";
                    app.TimeButton.Enable = "on";
                    app.SpectrogramButton.Enable = "on";
                    app.ProcessedButtonGroup.Enable = "on";
                    app.YesButton.Enable = "on";
                    app.NoButton.Enable = "on";
                    app.PlotButton.Enable = "on";
                    for k = 1:length(myTrainFiles)
                        baseFileName = myTrainFiles(k).name;
                        fullFileName = fullfile(myTrainDir, baseFileName);
                        [app.trainx_array{k}, app.train_fs{k}] = audioread(fullFileName);
                        if (size(app.trainx_array{k},2) ~= 1)
                            app.trainx_array{k}(:,2) = [];
                        end
                        app.trainx_preprocessed_array{k} = app.trainx_array{k} - mean(app.trainx_array{k});
                        app.trainx_preprocessed_array{k} = app.trainx_preprocessed_array{k} / max(abs(app.trainx_preprocessed_array{k}));
                        threshold = 0.1;
                        thresholdIndices = find(abs(app.trainx_preprocessed_array{k}) > threshold);
                        if ~isempty(thresholdIndices)
                            firstIndex = thresholdIndices(1);
                            lastIndex = thresholdIndices(end);
                            app.trainx_preprocessed_array{k} = app.trainx_preprocessed_array{k}(firstIndex:lastIndex);
                        end
                    end
                end
            end

            if ~isempty(app.testFileNames)
                app.PredictButton.Enable = "on";
            end
        end

        % Button pushed function: LoadTestDataButton
        function LoadTestDataButtonPushed(app, event)
            myTestDir = uigetdir;

            if myTestDir ~= 0
                myTestFiles = dir(fullfile(myTestDir,'*.wav'));
                if ~isempty(myTestFiles)
                    app.testFileNames = {myTestFiles.name};
                    app.Lamp2.Color = [0,1,0];
                    app.TestDataListBoxLabel.Enable = "on";
                    app.TestDataListBox.Enable = "on";
                    app.TestDataListBox.Items = app.trainFileNames;
                    app.VisualizationButtonGroup.Enable = "on";
                    app.TimeButton.Enable = "on";
                    app.SpectrogramButton.Enable = "on";
                    app.ProcessedButtonGroup.Enable = "on";
                    app.YesButton.Enable = "on";
                    app.NoButton.Enable = "on";
                    app.PlotButton.Enable = "on";
                    for k = 1:length(myTestFiles)
                        baseFileName = myTestFiles(k).name;
                        fullFileName = fullfile(myTestDir, baseFileName);
                        [app.testx_array{k}, app.test_fs{k}] = audioread(fullFileName);
                        if (size(app.testx_array{k},2) ~= 1)
                            app.testx_array{k}(:,2) = [];
                        end
                        app.testx_preprocessed_array{k} = app.testx_array{k} - mean(app.testx_array{k});
                        app.testx_preprocessed_array{k} = app.testx_preprocessed_array{k} / max(abs(app.testx_preprocessed_array{k}));
                        threshold = 0.1;
                        thresholdIndices = find(abs(app.testx_preprocessed_array{k}) > threshold);
                        if ~isempty(thresholdIndices)
                            firstIndex = thresholdIndices(1);
                            lastIndex = thresholdIndices(end);
                            app.testx_preprocessed_array{k} = app.testx_preprocessed_array{k}(firstIndex:lastIndex);
                        end
                    end
                end
            end

            if ~isempty(app.trainFileNames)
                app.PredictButton.Enable = "on";
            end
        end

        % Button pushed function: PlotButton
        function PlotButtonPushed(app, event)
            if ~isempty(app.TrainingDataListBox.Value) || ~isempty(app.TestDataListBox.Value)
                if xor(~isempty(app.TrainingDataListBox.Value), ~isempty(app.TestDataListBox.Value))
                    if ~isempty(app.TrainingDataListBox.Value)
                        if app.YesButton.Value
                            if app.TimeButton.Value
                                app.UIAxes.Visible = "on";
                                time = (0:length(app.trainx_preprocessed_array{app.TrainingDataListBox.ValueIndex})-1) / app.train_fs{app.TrainingDataListBox.ValueIndex};
                                plot(app.UIAxes, time, app.trainx_preprocessed_array{app.TrainingDataListBox.ValueIndex});
                                xlabel(app.UIAxes, 'Time (seconds)');
                                ylabel(app.UIAxes, 'Amplitude');
                                title(app.UIAxes, 'Time Spectra');
                                axis(app.UIAxes, 'tight');
                            else
                                app.UIAxes.Visible = "on";
                                [s, f, t] = spectrogram(app.trainx_preprocessed_array{app.TrainingDataListBox.ValueIndex}, hamming(app.N, 'periodic'), app.N - app.M, app.N, app.train_fs{app.TrainingDataListBox.ValueIndex});
                                imagesc(app.UIAxes, t, f, 10*log10(abs(s)));
                                axis(app.UIAxes, 'xy');
                                xlabel(app.UIAxes, 'Time (seconds)');
                                ylabel(app.UIAxes, 'Frequency (Hz)');
                                title(app.UIAxes, 'Spectrogram');
                                colorbar(app.UIAxes);
                                axis(app.UIAxes, 'tight');
                            end
                        else
                            if app.TimeButton.Value
                                app.UIAxes.Visible = "on";   
                                time = (0:length(app.trainx_array{app.TrainingDataListBox.ValueIndex})-1) / app.train_fs{app.TrainingDataListBox.ValueIndex};
                                plot(app.UIAxes, time, app.trainx_array{app.TrainingDataListBox.ValueIndex});
                                xlabel(app.UIAxes, 'Time (seconds)');
                                ylabel(app.UIAxes, 'Amplitude');
                                title(app.UIAxes, 'Time Spectra');
                                axis(app.UIAxes, 'tight');
                            else
                                app.UIAxes.Visible = "on";
                                [s, f, t] = spectrogram(app.trainx_array{app.TrainingDataListBox.ValueIndex}, hamming(app.N, 'periodic'), app.N - app.M, app.N, app.train_fs{app.TrainingDataListBox.ValueIndex});
                                imagesc(app.UIAxes, t, f, 10*log10(abs(s)));
                                axis(app.UIAxes, 'xy');
                                xlabel(app.UIAxes, 'Time (seconds)');
                                ylabel(app.UIAxes, 'Frequency (Hz)');
                                title(app.UIAxes, 'Spectrogram');
                                colorbar(app.UIAxes);
                                axis(app.UIAxes, 'tight');
                            end
                        end
                    elseif ~isempty(app.TestDataListBox.Value)
                        if app.YesButton.Value
                            if app.TimeButton.Value
                                app.UIAxes.Visible = "on";
                                time = (0:length(app.testx_preprocessed_array{app.TestDataListBox.ValueIndex})-1) / app.test_fs{app.TestDataListBox.ValueIndex};
                                plot(app.UIAxes, time, app.testx_preprocessed_array{app.TestDataListBox.ValueIndex});
                                xlabel(app.UIAxes, 'Time (seconds)');
                                ylabel(app.UIAxes, 'Amplitude');
                                title(app.UIAxes, 'Time Spectra');
                                axis(app.UIAxes, 'tight');
                            else
                                app.UIAxes.Visible = "on";
                                [s, f, t] = spectrogram(app.testx_preprocessed_array{app.TestDataListBox.ValueIndex}, hamming(app.N, 'periodic'), app.N - app.M, app.N, app.test_fs{app.TestDataListBox.ValueIndex});
                                imagesc(app.UIAxes, t, f, 10*log10(abs(s)));
                                axis(app.UIAxes, 'xy');
                                xlabel(app.UIAxes, 'Time (seconds)');
                                ylabel(app.UIAxes, 'Frequency (Hz)');
                                title(app.UIAxes, 'Spectrogram');
                                colorbar(app.UIAxes);
                                axis(app.UIAxes, 'tight');
                            end
                        else
                            if app.TimeButton.Value
                                app.UIAxes.Visible = "on";   
                                time = (0:length(app.testx_array{app.TestDataListBox.ValueIndex})-1) / app.test_fs{app.TestDataListBox.ValueIndex};
                                plot(app.UIAxes, time, app.testx_array{app.TestDataListBox.ValueIndex});
                                xlabel(app.UIAxes, 'Time (seconds)');
                                ylabel(app.UIAxes, 'Amplitude');
                                title(app.UIAxes, 'Time Spectra');
                                axis(app.UIAxes, 'tight');
                            else
                                app.UIAxes.Visible = "on";
                                [s, f, t] = spectrogram(app.testx_array{app.TestDataListBox.ValueIndex}, hamming(app.N, 'periodic'), app.N - app.M, app.N, app.test_fs{app.TestDataListBox.ValueIndex});
                                imagesc(app.UIAxes, t, f, 10*log10(abs(s)));
                                axis(app.UIAxes, 'xy');
                                xlabel(app.UIAxes, 'Time (seconds)');
                                ylabel(app.UIAxes, 'Frequency (Hz)');
                                title(app.UIAxes, 'Spectrogram');
                                colorbar(app.UIAxes);
                                axis(app.UIAxes, 'tight');
                            end
                        end
                    end
                end
            end
        end

        % Button pushed function: PredictButton
        function PredictButtonPushed(app, event)
            for k = 1:length(app.trainFileNames)
                [app.trainf_array{k},app.traint_array{k},app.trainmfcc_array{k}] = mfcc(app.trainx_preprocessed_array{k}, app.train_fs{k}, app.N);
            end

            for m = 1:size(app.trainmfcc_array,2)
                app.codebook{m} = LBG(app.trainmfcc_array{m}, app.CodebookSize);
            end

            for p = 1:(length(app.testFileNames))
                [app.testf_array{p},app.testt_array{p},app.testmfcc_array{p}] = mfcc(app.testx_preprocessed_array{p}, app.test_fs{p}, app.N);

                test_speaker_mfcc = app.testmfcc_array{p};
                test_speaker_mfcc(1,:) = [];
                centroid_distances = zeros(1, size(test_speaker_mfcc,2));
                average_centroid_distance = zeros(1, size(app.codebook,2));

                for i = 1:size(app.codebook,2)
                    for j = 1:size(test_speaker_mfcc,2)
                        distances = zeros(1, app.CodebookSize);
                        for l = 1:app.CodebookSize
                            distances(l) = norm(test_speaker_mfcc(:, j) - cell2mat(app.codebook{i}(:,l)));
                        end
                        centroid_distances(j) = min(distances);
                    end
                    average_centroid_distance(i) = mean(centroid_distances);
                end

                [~, classification(p)] = min(average_centroid_distance);
            end

            for q = 1:length(classification)
                predicted_class{q} = app.trainFileNames{classification(q)};
            end

            T = table(app.testFileNames', predicted_class', 'VariableNames', {'Actual Class', 'Predicted Class'});
            app.UITable.Data = T;

            accuracy = 0;
            for row = 1:height(T)
                if isequal(T{row, 1},T{row, 2})
                    accuracy = accuracy + 1;
                end
                if ~isequal(T{row, 1},T{row, 2})
                    disp(T{row, 1});
                    disp(T{row, 2});
                end
            end

            percent_correct = accuracy/length(app.testFileNames)*100;

            string = "Model accuracy = " + num2str(percent_correct) + "%";
            f = msgbox(string);
        end

        % Button pushed function: ClearButton
        function ClearButtonPushed(app, event)
            app.trainx_array = [];
            app.trainx_preprocessed_array = [];
            app.trainf_array = [];
            app.traint_array = [];
            app.trainmfcc_array = [];
            app.train_fs = [];
            app.trainFileNames = [];
    
            app.testx_array = [];
            app.testx_preprocessed_array = [];
            app.testf_array = [];
            app.testt_array = [];
            app.testmfcc_array = [];
            app.test_fs = [];
            app.testFileNames = [];

            app.codebook = [];

            app.Lamp.Color = [1,0,0];
            app.Lamp2.Color = [1,0,0];

            cla(app.UIAxes, 'reset')

            app.PredictButton.Enable = "off";
            app.PlotButton.Enable = "off";
            app.TrainingDataListBoxLabel.Enable = "off";
            app.TrainingDataListBox.Enable = "off";
            app.TrainingDataListBox.Items = {};
            app.TestDataListBoxLabel.Enable = "off";
            app.TestDataListBox.Enable = "off";
            app.TestDataListBox.Items = {};
            app.VisualizationButtonGroup.Enable = "off";
            app.TimeButton.Enable = "off";
            app.SpectrogramButton.Enable = "off";
            app.ProcessedButtonGroup.Enable = "off";
            app.YesButton.Enable = "off";
            app.NoButton.Enable = "off";
            app.UITable.Data = {};
            app.UIAxes.Visible = "off";
        end

        % Value changed function: NEditField
        function NEditFieldValueChanged(app, event)
            app.N = app.NEditField.Value;
        end

        % Value changed function: MEditField
        function MEditFieldValueChanged(app, event)
            app.CodebookSize = app.MEditField.Value;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes.Visible = 'off';
            app.UITable = uitable(app.UIFigure);
            app.UITable.ColumnName = {'Actual Class'; 'Predicted Class'};
            app.UITable.RowName = {};
            app.LoadTrainingDataButton = uibutton(app.UIFigure, 'push');
            app.LoadTrainingDataButton.ButtonPushedFcn = createCallbackFcn(app, @LoadTrainingDataButtonPushed, true);
            app.LoadTrainingDataButton.Text = 'Load Training Data';
            app.LoadTestDataButton = uibutton(app.UIFigure, 'push');
            app.LoadTestDataButton.ButtonPushedFcn = createCallbackFcn(app, @LoadTestDataButtonPushed, true);
            app.LoadTestDataButton.Text = 'Load Test Data';
            app.Lamp = uilamp(app.UIFigure);
            app.Lamp2 = uilamp(app.UIFigure);
            app.TrainingDataListBoxLabel = uilabel(app.UIFigure);
            app.TrainingDataListBoxLabel.Tag = 'TrainingDataText';
            app.TrainingDataListBoxLabel.Enable = 'off';
            app.TrainingDataListBoxLabel.Text = 'Training Data';
            app.TrainingDataListBox = uilistbox(app.UIFigure);
            app.TrainingDataListBox.Items = {};
            app.TrainingDataListBox.Multiselect = 'on';
            app.TrainingDataListBox.Enable = 'off';
            app.TrainingDataListBox.Value = {};
            app.TestDataListBoxLabel = uilabel(app.UIFigure);
            app.TestDataListBoxLabel.Tag = 'TrainingDataText';
            app.TestDataListBoxLabel.Enable = 'off';
            app.TestDataListBoxLabel.Text = 'Test Data';
            app.TestDataListBox = uilistbox(app.UIFigure);
            app.TestDataListBox.Items = {};
            app.TestDataListBox.Multiselect = 'on';
            app.TestDataListBox.Enable = 'off';
            app.TestDataListBox.Value = {};
            app.VisualizationButtonGroup = uibuttongroup(app.UIFigure);
            app.VisualizationButtonGroup.Enable = 'off';
            app.VisualizationButtonGroup.Title = 'Visualization';
            app.TimeButton = uiradiobutton(app.VisualizationButtonGroup);
            app.TimeButton.Enable = 'off';
            app.TimeButton.Text = 'Time';
            app.TimeButton.Value = true;
            app.SpectrogramButton = uiradiobutton(app.VisualizationButtonGroup);
            app.SpectrogramButton.Enable = 'off';
            app.SpectrogramButton.Text = 'Spectrogram';
            app.ProcessedButtonGroup = uibuttongroup(app.UIFigure);
            app.ProcessedButtonGroup.Enable = 'off';
            app.ProcessedButtonGroup.Title = 'Processed';
            app.YesButton = uiradiobutton(app.ProcessedButtonGroup);
            app.YesButton.Enable = 'off';
            app.YesButton.Text = 'Yes';
            app.YesButton.Value = true;
            app.NoButton = uiradiobutton(app.ProcessedButtonGroup);
            app.NoButton.Enable = 'off';
            app.NoButton.Text = 'No';
            app.PredictButton = uibutton(app.UIFigure, 'push');
            app.PredictButton.ButtonPushedFcn = createCallbackFcn(app, @PredictButtonPushed, true);
            app.PredictButton.Enable = 'off';
            app.PredictButton.Text = 'Predict';
            app.PlotButton = uibutton(app.UIFigure, 'push');
            app.PlotButton.ButtonPushedFcn = createCallbackFcn(app, @PlotButtonPushed, true);
            app.PlotButton.Enable = 'off';
            app.PlotButton.Text = 'Plot';
            app.ClearButton = uibutton(app.UIFigure, 'push');
            app.ClearButton.ButtonPushedFcn = createCallbackFcn(app, @ClearButtonPushed, true);
            app.ClearButton.Text = 'Clear';
            app.NEditFieldLabel = uilabel(app.UIFigure);
            app.NEditFieldLabel.Text = 'N';
            app.NEditField = uieditfield(app.UIFigure, 'numeric');
            app.NEditField.ValueChangedFcn = createCallbackFcn(app, @NEditFieldValueChanged, true);
            app.NEditField.Value = 256;
            app.MEditFieldLabel = uilabel(app.UIFigure);
            app.MEditFieldLabel.Text = 'M';
            app.MEditField = uieditfield(app.UIFigure, 'numeric');
            app.MEditField.ValueChangedFcn = createCallbackFcn(app, @MEditFieldValueChanged, true);
            app.MEditField.Value = 32;
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = EEC201FinalProject

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end